classdef SmartChair < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GenderEditField           matlab.ui.control.EditField
        GenderEditFieldLabel      matlab.ui.control.Label
        AgeEditField              matlab.ui.control.NumericEditField
        AgeEditFieldLabel         matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        NameEditFieldLabel        matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        ImportButton              matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        AduinoPortEditField       matlab.ui.control.EditField
        AduinoPortEditFieldLabel  matlab.ui.control.Label
        BaudRateEditField         matlab.ui.control.EditField
        BaudRateEditFieldLabel    matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        balance % Description
    end
    
    properties (Access = private)
        name % Description
        gender % Description
        age % Description
        standingUp_time 
        RMS_distance_ap
        RMS_distance_ml
        total_cop_path
        mean_velocity
        CoPapO_timeTable 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.AduinoPortEditField.Value~="" && app.BaudRateEditField.Value ~=""
               
                COM_PORT = app.AduinoPortEditField.Value;
                baudrate = app.BaudRateEditField.Value;
                
%                  serial_data_collecter(COM_PORT,baudrate);
                [app.standingUp_time, app.RMS_distance_ap, app.RMS_distance_ml, app.total_cop_path, app.mean_velocity,app.CoPapO_timeTable] = random_force_generator();
                plot(app.UIAxes,app.CoPapO_timeTable.Var1);
%                
            else
               errordlg('Please enter Aduino Port and Baud Rate','Warning')
            end
            
 
        end

        % Callback function
        function ConfirmButtonPushed(app, event)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        standingUp_time = app.standingUp_time;
        RMS_distance_ap = app.RMS_distance_ap;
        RMS_distance_ml = app.RMS_distance_ml;
        total_cop_path = app.RMS_distance_ml;
        mean_velocity = app.mean_velocity;
        CoPapO_timeTable = app.CoPapO_timeTable;
        
         file_name = strcat(app.name,num2str(app.age),app.gender,'.mat');
         save(file_name,'standingUp_time',"RMS_distance_ap","RMS_distance_ml","total_cop_path","mean_velocity","CoPapO_timeTable",'-mat');
           
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.name = app.NameEditField.Value;
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            file = uigetfile;
            load test.mat
            T1 = CoPapO_timeTable;
           plot(app.UIAxes,T1.Var1);
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            app.age = app.AgeEditField.Value;
            
        end

        % Value changed function: GenderEditField
        function GenderEditFieldValueChanged(app, event)
            app.gender = app.GenderEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GenderEditField = uieditfield(app.GridLayout, 'text');
            app.GenderEditField.ValueChangedFcn = createCallbackFcn(app, @GenderEditFieldValueChanged, true);
            app.GenderEditFieldLabel = uilabel(app.GridLayout);
            app.GenderEditFieldLabel.Text = 'Gender';
            app.AgeEditField = uieditfield(app.GridLayout, 'numeric');
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.AgeEditFieldLabel = uilabel(app.GridLayout);
            app.AgeEditFieldLabel.Text = 'Age';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditFieldLabel = uilabel(app.GridLayout);
            app.NameEditFieldLabel.Text = 'Name';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = '+';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.Text = 'Stop';
            app.AduinoPortEditField = uieditfield(app.GridLayout, 'text');
            app.AduinoPortEditFieldLabel = uilabel(app.GridLayout);
            app.AduinoPortEditFieldLabel.Text = 'Aduino Port';
            app.BaudRateEditField = uieditfield(app.GridLayout, 'text');
            app.BaudRateEditFieldLabel = uilabel(app.GridLayout);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Balance')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'COP AP')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartChair

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end