classdef Smart_Chair_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PersonalInfoPanel             matlab.ui.container.Panel
        GenderButtonGroup             matlab.ui.container.ButtonGroup
        MaleButton                    matlab.ui.control.RadioButton
        FemaleButton                  matlab.ui.control.RadioButton
        NameEditFieldLabel            matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        AgeEditFieldLabel             matlab.ui.control.Label
        AgeEditField                  matlab.ui.control.NumericEditField
        HealthIssuesEditFieldLabel    matlab.ui.control.Label
        HealthIssuesEditField         matlab.ui.control.EditField
        DataCollectionPanel           matlab.ui.container.Panel
        StartButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        RateEditFieldLabel            matlab.ui.control.Label
        RateEditField                 matlab.ui.control.NumericEditField
        VibrationFrequencyEditFieldLabel  matlab.ui.control.Label
        VibrationFrequencyEditField   matlab.ui.control.NumericEditField
        SetButton                     matlab.ui.control.Button
        FigurePanel                   matlab.ui.container.Panel
        StandingUpTimeEditFieldLabel  matlab.ui.control.Label
        StandingUpTimeEditField       matlab.ui.control.NumericEditField
        RMSDistanceMLEditFieldLabel   matlab.ui.control.Label
        RMSDistanceMLEditField        matlab.ui.control.NumericEditField
        RMSDistanceAPEditFieldLabel   matlab.ui.control.Label
        RMSDistanceAPEditField        matlab.ui.control.NumericEditField
        TotalCOPPathEditFieldLabel    matlab.ui.control.Label
        TotalCOPPathEditField         matlab.ui.control.NumericEditField
        MeanVelocityEditFieldLabel    matlab.ui.control.Label
        MeanVelocityEditField         matlab.ui.control.NumericEditField
        Panel                         matlab.ui.container.Panel
        Tree                          matlab.ui.container.Tree
        COPNode                       matlab.ui.container.TreeNode
        HeatmapNode                   matlab.ui.container.TreeNode
        RawNode                       matlab.ui.container.TreeNode
        PositionNode                  matlab.ui.container.TreeNode
        SwayNode                      matlab.ui.container.TreeNode
        RawXYZNode                    matlab.ui.container.TreeNode
        NPLNode                       matlab.ui.container.TreeNode
        JawClenchNode                 matlab.ui.container.TreeNode
        IntensityNode                 matlab.ui.container.TreeNode
        GripNode                      matlab.ui.container.TreeNode
        IntensityNode_2               matlab.ui.container.TreeNode
        SelectButton                  matlab.ui.control.Button
        PopOutButton                  matlab.ui.control.Button
        PortEditFieldLabel            matlab.ui.control.Label
        PortEditField                 matlab.ui.control.EditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        name % Description
        gender % Description
        age % Description
        standingUp_time 
        RMS_distance_ap
        RMS_distance_ml
        total_cop_path
        mean_velocity
        CoPapO_timeTable 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             if app.PortEditField.Value ~=0 && app.RateEditField.Value ~=0
               
                COM_PORT = app.PortEditField.Value
                baudrate = app.RateEditField.Value
                
                 standingUp_time, RMS_distance_ap, RMS_distance_ml, total_cop_path, mean_velocity = data_collecter(COM_PORT,baudrate);
                  app.StandingUpTimeEditField.Value = standingUp_time;
            app.RMSDistanceAPEditField.Value = RMS_distance_ap;
            app.RMSDistanceMLEditField.Value = RMS_distance_ml;
            app.TotalCOPPathEditField.Value = total_cop_path;
            app.MeanVelocityEditField.Value = mean_velocity;
%                 [app.standingUp_time, app.RMS_distance_ap, app.RMS_distance_ml, app.total_cop_path, app.mean_velocity,app.CoPapO_timeTable] = random_force_generator();
%                 plot(app.UIAxes,app.CoPapO_timeTable.Var1);
%                 hold(app.UIAxes);
%                
            else
               errordlg('Please enter Aduino Port and Baud Rate','Warning')
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            standingUp_time = app.standingUp_time;
            RMS_distance_ap = app.RMS_distance_ap;
            RMS_distance_ml = app.RMS_distance_ml;
            total_cop_path = app.RMS_distance_ml;
            mean_velocity = app.mean_velocity;
            CoPapO_timeTable = app.CoPapO_timeTable;


            file_name = strcat(app.name,num2str(app.age),'.mat');
            save(file_name,'standingUp_time',"RMS_distance_ap","RMS_distance_ml","total_cop_path","mean_velocity","CoPapO_timeTable",'-mat');
           
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            file = uigetfile;
            load test.mat
            T1 = CoPapO_timeTable;
            plot(app.UIAxes,T1.Var1);
           
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.name = app.NameEditField.Value;
            
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            app.age = app.AgeEditField.Value;
            
        end

        % Selection changed function: GenderButtonGroup
        function GenderButtonGroupSelectionChanged(app, event)
            if app.MaleButton
                app.gender = "male";
            else
                app.gender = "female";
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
             arduinoCom = serial(app.PortEditField.Value,app.RateEditField.Value);                                                      
             sendData = app.VibrationFrequencyEditField.Value;
             fopen(arduinoCom);
             fprintf(arduinoCom,'%i',sendData); %this will send 5 to the arduino
             fscanf(arduinoCom)    %this will read response
                       %or use BytesAvailableFcn property of serial
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if app.Tree.SelectedNodes.NodeData == 3
                plot(app.UIAxes,F1);
                hold (app.UIAxes);
                plot(app.UIAxes,F2);
                hold (app.UIAxes);
                plot(app.UIAxes,F3);
                hold (app.UIAxes);
                plot(app.UIAxes,F4);
            elseif app.Tree.SelectedNodes.NodeData == 6
                plot(app.UIAxes,GX);
                hold (app.UIAxes);
                plot(app.UIAxes,GY);
                hold (app.UIAxes);
                plot(app.UIAxes,GZ);
            elseif app.Tree.SelectedNodes.NodeData == 9
                plot(app.UIAxes,GripStrength);      
            elseif app.Tree.SelectedNodes.NodeData == 11
                plot(app.UIAxes,JawStrength);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PersonalInfoPanel = uipanel(app.UIFigure);
            app.PersonalInfoPanel.Title = 'Personal Info';
            app.GenderButtonGroup = uibuttongroup(app.PersonalInfoPanel);
            app.GenderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GenderButtonGroupSelectionChanged, true);
            app.GenderButtonGroup.Title = 'Gender';
            app.MaleButton = uiradiobutton(app.GenderButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.GenderButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.NameEditFieldLabel = uilabel(app.PersonalInfoPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PersonalInfoPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.AgeEditFieldLabel = uilabel(app.PersonalInfoPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.PersonalInfoPanel, 'numeric');
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.HealthIssuesEditFieldLabel = uilabel(app.PersonalInfoPanel);
            app.HealthIssuesEditFieldLabel.Text = 'Health Issues';
            app.HealthIssuesEditField = uieditfield(app.PersonalInfoPanel, 'text');
            app.DataCollectionPanel = uipanel(app.UIFigure);
            app.DataCollectionPanel.Title = 'Data Collection';
            app.StartButton = uibutton(app.DataCollectionPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.DataCollectionPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.DataCollectionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ImportButton = uibutton(app.DataCollectionPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.RateEditFieldLabel = uilabel(app.DataCollectionPanel);
            app.RateEditFieldLabel.Text = 'Rate';
            app.RateEditField = uieditfield(app.DataCollectionPanel, 'numeric');
            app.VibrationFrequencyEditFieldLabel = uilabel(app.DataCollectionPanel);
            app.VibrationFrequencyEditFieldLabel.Text = 'Vibration Frequency';
            app.VibrationFrequencyEditField = uieditfield(app.DataCollectionPanel, 'numeric');
            app.SetButton = uibutton(app.DataCollectionPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.FigurePanel = uipanel(app.UIFigure);
            app.FigurePanel.Title = 'Figure';
            app.StandingUpTimeEditFieldLabel = uilabel(app.FigurePanel);
            app.StandingUpTimeEditFieldLabel.Text = 'Standing Up Time';
            app.StandingUpTimeEditField = uieditfield(app.FigurePanel, 'numeric');
            app.RMSDistanceMLEditFieldLabel = uilabel(app.FigurePanel);
            app.RMSDistanceMLEditFieldLabel.Text = 'RMS Distance ML';
            app.RMSDistanceMLEditField = uieditfield(app.FigurePanel, 'numeric');
            app.RMSDistanceAPEditFieldLabel = uilabel(app.FigurePanel);
            app.RMSDistanceAPEditFieldLabel.Text = 'RMS Distance AP';
            app.RMSDistanceAPEditField = uieditfield(app.FigurePanel, 'numeric');
            app.TotalCOPPathEditFieldLabel = uilabel(app.FigurePanel);
            app.TotalCOPPathEditFieldLabel.Text = 'Total COP Path';
            app.TotalCOPPathEditField = uieditfield(app.FigurePanel, 'numeric');
            app.MeanVelocityEditFieldLabel = uilabel(app.FigurePanel);
            app.MeanVelocityEditFieldLabel.Text = 'Mean Velocity';
            app.MeanVelocityEditField = uieditfield(app.FigurePanel, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Tree = uitree(app.Panel);
            app.COPNode = uitreenode(app.Tree);
            app.COPNode.NodeData = 1;
            app.COPNode.Text = 'COP';
            app.HeatmapNode = uitreenode(app.COPNode);
            app.HeatmapNode.NodeData = 2;
            app.HeatmapNode.Text = 'Heatmap';
            app.RawNode = uitreenode(app.COPNode);
            app.RawNode.NodeData = 3;
            app.RawNode.Text = 'Raw';
            app.PositionNode = uitreenode(app.COPNode);
            app.PositionNode.NodeData = 4;
            app.PositionNode.Text = 'Position';
            app.SwayNode = uitreenode(app.Tree);
            app.SwayNode.NodeData = 5;
            app.SwayNode.Text = 'Sway';
            app.RawXYZNode = uitreenode(app.SwayNode);
            app.RawXYZNode.NodeData = 6;
            app.RawXYZNode.Text = 'Raw XYZ';
            app.NPLNode = uitreenode(app.SwayNode);
            app.NPLNode.NodeData = 7;
            app.NPLNode.Text = 'NPL';
            app.JawClenchNode = uitreenode(app.Tree);
            app.JawClenchNode.NodeData = 8;
            app.JawClenchNode.Text = 'Jaw Clench';
            app.IntensityNode = uitreenode(app.JawClenchNode);
            app.IntensityNode.NodeData = 9;
            app.IntensityNode.Text = 'Intensity';
            app.GripNode = uitreenode(app.Tree);
            app.GripNode.NodeData = 10;
            app.GripNode.Text = 'Grip';
            app.IntensityNode_2 = uitreenode(app.GripNode);
            app.IntensityNode_2.NodeData = 11;
            app.IntensityNode_2.Text = 'Intensity';
            app.SelectButton = uibutton(app.Panel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.PopOutButton = uibutton(app.Panel, 'push');
            app.PopOutButton.Text = 'Pop Out';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Smart_Chair_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end